classdef chooseyourproduct_3prodotti_def < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UITable3                       matlab.ui.control.Table
        TimeHorizonvsRiskpropButton    matlab.ui.container.ButtonGroup
        TimeHorizonButton              matlab.ui.control.RadioButton
        RiskButton                     matlab.ui.control.RadioButton
        TimeHorizonSpinner             matlab.ui.control.Spinner
        TimeHorizonSpinnerLabel        matlab.ui.control.Label
        REFRESHButton                  matlab.ui.control.Button
        UITable2                       matlab.ui.control.Table
        UITable                        matlab.ui.control.Table
        TextArea                       matlab.ui.control.TextArea
        TextAreaLabel                  matlab.ui.control.Label
        WealthSlider                   matlab.ui.control.Slider
        WealthSliderLabel              matlab.ui.control.Label
        IncomeSliderLabel              matlab.ui.control.Label
        IncomeSlider                   matlab.ui.control.Slider
        FamilyMembersSpinnerLabel      matlab.ui.control.Label
        FamilyMembersSpinner           matlab.ui.control.Spinner
        AgeSliderLabel                 matlab.ui.control.Label
        AgeSlider                      matlab.ui.control.Slider
        FinancialEducationSliderLabel  matlab.ui.control.Label
        FinancialEducationSlider       matlab.ui.control.Slider
        RiskPropensitySliderLabel      matlab.ui.control.Label
        RiskPropensitySlider           matlab.ui.control.Slider
        GenderButtonGroup              matlab.ui.container.ButtonGroup
        FemaleButton                   matlab.ui.control.RadioButton
        MaleButton                     matlab.ui.control.RadioButton
        ProductButtonGroup             matlab.ui.container.ButtonGroup
        IncomeButton                   matlab.ui.control.RadioButton
        AccumulationButton             matlab.ui.control.RadioButton
        GOButton                       matlab.ui.control.StateButton
    end

    % IN ORDER TO WORK ALL THE INPUTS OF THE APP MUST BE FILLED, THIS 
    % HOLDS ALSO FOR DOUBLE CHOICE BUTTON
    % EXAMPLE IF I WANT TO CHOOSE GENDER 'MALE' I NEED TO PUSH 'FEMALE'
    % AND THEN MALE AGAIN 
    
    properties (Access = public)
        datanew = []; % Description
        
        outputincwearatio;
        outputage;
        outputfined;
        outputincome;
        outputwealth;
        outputfamilymembers;
        outputriskpropensity;
        outputgender;
        outputproduct;
        prodotti;
        outputInvestmentTimeHorizon;
        outputTimeHorizonvsRisk;
    end


    methods (Access = private)
        
        function results = prediction(app)
            disp(app.ProductButtonGroup.SelectedObject.Text)
            disp("Accumulation")
            if(strcmp(app.ProductButtonGroup.SelectedObject.Text,"Accumulation")) %assicurati che app.outputproduct sia una stringa
            load("model_accTIME.mat",'MdlOptimum_acc');
            [labels,~] = predict(MdlOptimum_acc,app.datanew);
            disp(labels)
                if (labels == "1")
                    results = app.ProductButtonGroup.SelectedObject.Text;
                else
                    results = 0; %oppure trova alternativa quando la label è 0
                end
            else
                %cambia model.mat con income
                load("model_incTIME.mat",'MdlOptimum_inc');
                disp(app.datanew)
                [labels,~] = predict(MdlOptimum_inc,app.datanew);
                disp('labels')
                disp(labels)
                if (labels == "1")
                    results = app.ProductButtonGroup.SelectedObject.Text; 
                else
                    results = 0; %oppure trova alternativa quando la label è 0
                end
            end
        end
        
        function association(app)
            load('ProductsNEW.mat');
            
            if(strcmp(app.ProductButtonGroup.SelectedObject.Text,"Accumulation"))
                DesiredProducts = Products(Products.Type == 1,:);
            else
                DesiredProducts = Products(Products.Type == 0,:);
                DesiredProducts = DesiredProducts(1:4,:);
            end
            
            NBA_IDProduct = zeros(1,3);
            RecommendedRiskLevel = zeros(1,3);
            RecommendedTimeHorizon=zeros(1,3);
            minRisk = min(DesiredProducts.Risk);

            v_1=[];
            M_1=[];
            M_TH=[];
          
            if app.outputriskpropensity>minRisk
                
                for j=1:length(DesiredProducts.ID)
                        if DesiredProducts.Risk(j)<app.outputriskpropensity
                            v_1=[v_1 DesiredProducts.ID(j)];
                            M_1=[M_1 DesiredProducts.Risk(j)];
                            M_TH=[M_TH DesiredProducts.TimeHorizon(j)];
                        end
                end
            if(strcmp(app.TimeHorizonvsRiskpropButton.SelectedObject.Text,"Risk propensity"))
               if length(v_1)>3
               [M, Ind]= sort(M_1,"descend");
               v(1:3)=v_1(Ind(3:-1:1));              
               M=M(3:-1:1);
               M_TH=M_TH(Ind(3:-1:1));
               else 
               [M, Ind]= sort(M_1,"ascend"); 
                v=v_1(Ind);
                M_TH=M_TH(Ind);
               end
            else
                 diff_TH=abs(app.outputInvestmentTimeHorizon-M_TH);
                 [~,ord]=sort(diff_TH,'ascend');
               if length(diff_TH)>3
                  v(1:3)=v_1(ord(1:3));
                  M=M_1(ord(1:3));
                  M_TH=M_TH(ord(1:3));   
               else
                   v=v_1(ord);
                   M_TH=M_TH(ord);
                   M=M_1(ord);
               end
            end
            NBA_IDProduct(1:length(v))=v;
            RecommendedRiskLevel(1:length(M))=M;
            RecommendedTimeHorizon(1:length(M_TH))=M_TH;
            disp(NBA_IDProduct)
            disp(string(NBA_IDProduct))
            app.UITable.Data = string(NBA_IDProduct);
            app.UITable2.Data = RecommendedRiskLevel;
            app.UITable3.Data = RecommendedTimeHorizon;
            
            else
                if app.outputriskpropensity> Products.Risk(end)
                    NBA_IDProduct=Products.ID(end);
                    RecommendedRiskLevel=Products.Risk(end)
                    RecommendedTimeHorizon=Products.TimeHorizon(end);
                    disp(NBA_IDProduct)
                    disp(string(NBA_IDProduct))
                    app.UITable.Data = string(NBA_IDProduct);
                    app.UITable2.Data = RecommendedRiskLevel;
                    app.UITable3.Data = RecommendedTimeHorizon;

                end
            end
        end
        
        
        function Refresh(app)
           % Set form fields to default values
            app.AgeSlider.Value = 18;
            app.FamilyMembersSpinner.Value= 1;
            app.FinancialEducationSlider.Value = 0;
            app.RiskPropensitySlider.Value = 0;
            app.IncomeSlider.Value = 1;
            app.WealthSlider.Value = 10;
%            app.MaleButton.Value = false;
            app.FemaleButton.Value = false;
            app.IncomeButton.Value = false;
            app.TextArea.Value = '';
            app.TimeHorizonSpinner.Value=1;
            app.TimeHorizonButton.Value = false;
%            app.UITable.ColumnEditable;
            app.UITable.Data = zeros(1,numel(app.UITable.ColumnName));
            app.UITable2.ColumnEditable;
            app.UITable2.Data = zeros(1,numel(app.UITable.ColumnName));
            app.UITable3.Data = zeros(1,numel(app.UITable.ColumnName));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.ColumnEditable;
            app.UITable.Data = zeros(1,numel(app.UITable.ColumnName));
            app.UITable2.ColumnEditable;
            app.UITable2.Data = zeros(1,numel(app.UITable.ColumnName));
            app.UITable3.ColumnEditable;
            app.UITable3.Data = zeros(1,numel(app.UITable.ColumnName));
        end

        % Value changed function: GOButton
        function GOButtonValueChanged(app, event)
            load("ScaleIncomeWealthRatio.mat","maxIncomeWealthRatio","minIncomeWealthRatio");
            app.outputincwearatio = rescale(app.outputincome/app.outputwealth,"InputMax",maxIncomeWealthRatio,"InputMin",minIncomeWealthRatio);
            app.datanew = [app.outputincwearatio app.outputage app.outputfined app.outputincome app.outputwealth app.outputInvestmentTimeHorizon];
            %carico modello e uso modello per predire app.datanew -> func
            
            if(strcmp(prediction(app),app.ProductButtonGroup.SelectedObject.Text)) %può essere o income o accumulation, non entrambi
                %ciclo for in cui associo i prodotti->func
                %app.prodotti = association(app);
                association(app);
                %salvo i prodotti e li mostro nella table->fun updatetable
                %app.UITable.Data = app.prodotti(1,:);
                %app.UITable2.Data = app.prodotti(2,:);
            else
                app.TextArea.Value = "Non associo nessun prodotto";
            %else non associo nulla, posso fare un display su un area di
            %testo
            end
            
        end

        % Value changed function: FinancialEducationSlider
        function FinancialEducationSliderValueChanged(app, event)
            value = app.FinancialEducationSlider.Value;
            load("ScaleFinancialEducation.mat",'minFinancialEducation','maxFinancialEducation');
            app.outputfined=rescale(value,"InputMax",maxFinancialEducation,"InputMin",minFinancialEducation);
            disp('Fin ed')
            disp( app.outputfined)
        end

        % Value changed function: WealthSlider
        function WealthSliderValueChanged(app, event)
            app.outputwealth = log(app.WealthSlider.Value);
            %load("ScalelogWealth.mat",'minlogWealth','maxlogWealth');
            %app.outputwealth = rescale(value,"InputMax",maxlogWealth,"InputMin",minlogWealth);
            disp('Wealth')
            disp( app.outputwealth)
        end

        % Value changed function: IncomeSlider
        function IncomeSliderValueChanged(app, event)
            app.outputincome = log(app.IncomeSlider.Value);
            %load("ScalelogIncome.mat",'minlogIncome','maxlogIncome');
            %app.outputincome = rescale(value,"InputMax",maxlogIncome,"InputMin",minlogIncome);
            disp('income')
            disp( app.outputincome)
        end

        % Value changed function: FamilyMembersSpinner
        function FamilyMembersSpinnerValueChanged(app, event)
            value = app.FamilyMembersSpinner.Value;
            load("ScaleFamilyMembers.mat",'minFamilyMembers','maxFamilyMembers');
            app.outputfamilymembers=rescale(value,"InputMax",maxFamilyMembers,"InputMin",minFamilyMembers);
            disp('family')
            disp(app.outputfamilymembers)
            %però si può anche omettere
        end

        % Value changed function: AgeSlider
        function AgeSliderValueChanged(app, event)
            value = app.AgeSlider.Value;
            load("ScaleAge.mat",'minAge','maxAge');
            app.outputage=rescale(value,"InputMax",maxAge,"InputMin",minAge);
            disp('age')
            disp(app.outputage)
        end

        % Value changed function: RiskPropensitySlider
        function RiskPropensitySliderValueChanged(app, event)
            value = app.RiskPropensitySlider.Value;
            load("ScaleRiskPropensity.mat",'minRiskPropensity','maxRiskPropensity');
            app.outputriskpropensity=rescale(value,"InputMax",maxRiskPropensity,"InputMin",minRiskPropensity);
            disp('risk prop')
            disp(app.outputriskpropensity)
        end

        % Selection changed function: GenderButtonGroup
        function GenderButtonGroupSelectionChanged(app, event)
            selectedButton = app.GenderButtonGroup.SelectedObject;
            app.outputgender = selectedButton;
            disp('gender')
            disp(app.outputgender)
        end

        % Selection changed function: ProductButtonGroup
        function ProductButtonGroupSelectionChanged(app, event)
            selectedButton = app.ProductButtonGroup.SelectedObject;
            app.outputproduct = selectedButton;
            disp('product')
            disp(app.outputproduct)
        end

        % Display data changed function: UITable2
        function UITable2DisplayDataChanged(app, event)
            app.UITable2.ColumnEditable;
            app.UITable2.Data = zeros(1,numel(app.UITable2.ColumnName));
            %newDisplayData = app.UITable2.DisplayData;
            
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            app.UITable.ColumnEditable;
            app.UITable.Data = zeros(1,numel(app.UITable.ColumnName));
            %newDisplayData = app.UITable.Data;
            
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
        end

        % Button pushed function: REFRESHButton
        function REFRESHButtonPushed(app, event)
            Refresh(app)
        end

        % Value changed function: TimeHorizonSpinner
        function TimeHorizonSpinnerValueChanged(app, event)
            value = app.TimeHorizonSpinner.Value;
%             load("InvestmentTimeHorizon.mat",'minInvestmentTimeHorizon','maxInvestmentTimeHorizon');
            minInvestementTimeHorizon=1;
            maxInvestmentTimeHorizon=10;
            app.outputInvestmentTimeHorizon=rescale(value,"InputMax",maxInvestmentTimeHorizon,"InputMin",minInvestementTimeHorizon);
            disp('time horizon')
            disp(app.outputInvestmentTimeHorizon)
        end

        % Selection changed function: TimeHorizonvsRiskpropButton
        function TimeHorizonvsRiskpropButtonSelectionChanged(app, event)
            selectedButton = app.TimeHorizonvsRiskpropButton.SelectedObject;
            app.outputTimeHorizonvsRisk = selectedButton;
            disp('TimeHorizonvsRiskprop')
            disp(app.outputTimeHorizonvsRisk)
        end

        % Display data changed function: UITable3
        function UITable3DisplayDataChanged(app, event)
            %newDisplayData = app.UITable3.DisplayData;
            app.UITable3.ColumnEditable;
            app.UITable3.Data = zeros(1,numel(app.UITable3.ColumnName));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GOButton = uibutton(app.UIFigure, 'state');
            app.GOButton.ValueChangedFcn = createCallbackFcn(app, @GOButtonValueChanged, true);
            app.GOButton.Text = 'GO!';
            app.ProductButtonGroup = uibuttongroup(app.UIFigure);
            app.ProductButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProductButtonGroupSelectionChanged, true);
            app.ProductButtonGroup.Title = 'Product';
            app.AccumulationButton = uiradiobutton(app.ProductButtonGroup);
            app.AccumulationButton.Text = 'Accumulation';
            app.AccumulationButton.Value = true;
            app.IncomeButton = uiradiobutton(app.ProductButtonGroup);
            app.IncomeButton.Text = 'Income';
            app.GenderButtonGroup = uibuttongroup(app.UIFigure);
            app.GenderButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GenderButtonGroupSelectionChanged, true);
            app.GenderButtonGroup.Title = 'Gender';
            app.MaleButton = uiradiobutton(app.GenderButtonGroup);
            app.MaleButton.Text = 'Male';
            app.MaleButton.Value = true;
            app.FemaleButton = uiradiobutton(app.GenderButtonGroup);
            app.FemaleButton.Text = 'Female';
            app.RiskPropensitySlider = uislider(app.UIFigure);
            app.RiskPropensitySlider.Limits = [0 1];
            app.RiskPropensitySlider.ValueChangedFcn = createCallbackFcn(app, @RiskPropensitySliderValueChanged, true);
            app.RiskPropensitySliderLabel = uilabel(app.UIFigure);
            app.RiskPropensitySliderLabel.Text = 'Risk Propensity';
            app.FinancialEducationSlider = uislider(app.UIFigure);
            app.FinancialEducationSlider.Limits = [0 1];
            app.FinancialEducationSlider.ValueChangedFcn = createCallbackFcn(app, @FinancialEducationSliderValueChanged, true);
            app.FinancialEducationSliderLabel = uilabel(app.UIFigure);
            app.FinancialEducationSliderLabel.Text = 'Financial Education';
            app.AgeSlider = uislider(app.UIFigure);
            app.AgeSlider.Limits = [18 100];
            app.AgeSlider.ValueChangedFcn = createCallbackFcn(app, @AgeSliderValueChanged, true);
            app.AgeSlider.Value = 18;
            app.AgeSliderLabel = uilabel(app.UIFigure);
            app.AgeSliderLabel.Text = 'Age';
            app.FamilyMembersSpinner = uispinner(app.UIFigure);
            app.FamilyMembersSpinner.Limits = [1 5];
            app.FamilyMembersSpinner.ValueChangedFcn = createCallbackFcn(app, @FamilyMembersSpinnerValueChanged, true);
            app.FamilyMembersSpinner.Value = 1;
            app.FamilyMembersSpinnerLabel = uilabel(app.UIFigure);
            app.FamilyMembersSpinnerLabel.Text = 'Family Members';
            app.IncomeSlider = uislider(app.UIFigure);
            app.IncomeSlider.Limits = [1 370];
            app.IncomeSlider.ValueChangedFcn = createCallbackFcn(app, @IncomeSliderValueChanged, true);
            app.IncomeSlider.Value = 1;
            app.IncomeSliderLabel = uilabel(app.UIFigure);
            app.IncomeSliderLabel.Text = {'Income'; ''};
            app.WealthSliderLabel = uilabel(app.UIFigure);
            app.WealthSliderLabel.Text = 'Wealth';
            app.WealthSlider = uislider(app.UIFigure);
            app.WealthSlider.Limits = [10 2250];
            app.WealthSlider.ValueChangedFcn = createCallbackFcn(app, @WealthSliderValueChanged, true);
            app.WealthSlider.Value = 10;
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Product 1'; 'Product 2'; 'Product 3'};
            app.UITable.RowName = {'Product Name'; ''};
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Risk Product 1'; 'Risk Product 2'; 'Risk Product 3'};
            app.UITable2.RowName = {'Product Risk'};
            app.UITable2.DisplayDataChangedFcn = createCallbackFcn(app, @UITable2DisplayDataChanged, true);
            app.REFRESHButton = uibutton(app.UIFigure, 'push');
            app.REFRESHButton.ButtonPushedFcn = createCallbackFcn(app, @REFRESHButtonPushed, true);
            app.REFRESHButton.Text = 'REFRESH!';
            app.TimeHorizonSpinnerLabel = uilabel(app.UIFigure);
            app.TimeHorizonSpinnerLabel.Text = 'Time Horizon';
            app.TimeHorizonSpinner = uispinner(app.UIFigure);
            app.TimeHorizonSpinner.Limits = [1 10];
            app.TimeHorizonSpinner.ValueChangedFcn = createCallbackFcn(app, @TimeHorizonSpinnerValueChanged, true);
            app.TimeHorizonSpinner.Value = 1;
            app.TimeHorizonvsRiskpropButton = uibuttongroup(app.UIFigure);
            app.TimeHorizonvsRiskpropButton.SelectionChangedFcn = createCallbackFcn(app, @TimeHorizonvsRiskpropButtonSelectionChanged, true);
            app.TimeHorizonvsRiskpropButton.Title = 'Risk Propensity vs Time horizon';
            app.RiskButton = uiradiobutton(app.TimeHorizonvsRiskpropButton);
            app.RiskButton.Text = 'Risk propensity';
            app.RiskButton.Value = true;
            app.TimeHorizonButton = uiradiobutton(app.TimeHorizonvsRiskpropButton);
            app.TimeHorizonButton.Text = 'Time horizon';
            app.UITable3 = uitable(app.UIFigure);
            app.UITable3.ColumnName = {'Time horizon 1'; 'Time horizon 2'; 'Time horizon 3'};
            app.UITable3.RowName = {'Time horizon'; ''};
            app.UITable3.DisplayDataChangedFcn = createCallbackFcn(app, @UITable3DisplayDataChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = chooseyourproduct_3prodotti_def

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end